classdef KalmanFilter2DExampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        StartButton                    matlab.ui.control.Button
        SamplingTimeTsLabel            matlab.ui.control.Label
        SamplingTimeTsEditField        matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel         matlab.ui.control.Label
        StopTimeEditField              matlab.ui.control.NumericEditField
        InitialPositioninXEditFieldLabel  matlab.ui.control.Label
        InitialPositioninXEditField    matlab.ui.control.NumericEditField
        InitialPositioninYEditFieldLabel  matlab.ui.control.Label
        InitialPositioninYEditField    matlab.ui.control.NumericEditField
        InitialVelocityinXEditFieldLabel  matlab.ui.control.Label
        InitialVelocityinXEditField    matlab.ui.control.NumericEditField
        InitialVelocityinYEditFieldLabel  matlab.ui.control.Label
        InitialVelocityinYEditField    matlab.ui.control.NumericEditField
        AccelerationinYEditFieldLabel  matlab.ui.control.Label
        AccelerationinYEditField       matlab.ui.control.NumericEditField
        AccelerationinXEditField_2Label  matlab.ui.control.Label
        AccelerationinXEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Setting up the known parameters x,x_hat,P,A,B,C,u(k),y(k),Z,Q,R

            Ts = app.SamplingTimeTsEditField.Value;                  % Sampling time 
            t_final = app.StopTimeEditField.Value;              % Simulation end time
            t = 0:Ts:t_final-Ts;       % Time axis
            numIters = length(t);      % Number of iterations
            
            xX = [app.InitialPositioninXEditField.Value;app.InitialVelocityinXEditField.Value];               % Initial State Vector
            x_hatX = xX;               % Initial State Vector Estimate
            PX = [1 0;0 1];            % Initial Covariance Matrix
            x_hat_previousX = xX;      % Previous State Vector
            P_previousX = PX;          % Previous Covariance Matrix
            
            xY = [app.InitialPositioninYEditField.Value;app.InitialVelocityinYEditField.Value];                % Initial State Vector
            x_hatY = xY;               % Initial State Vector Estimate
            PY = [1 0;0 1];            % Initial Covariance Matrix
            x_hat_previousY = xY;      % Previous State Vector
            P_previousY = PY;          % Previous Covariance Matrix
            
            A = [1   Ts ; 0 1];        % State Transition Matrix or Prediction Matrix
            B = [Ts^2/2 ; Ts];         % Input Control Matrix
            C = [1 0];                 % Measurement Matrix
            
            uX = app.AccelerationinXEditField.Value*ones(1,length(t));      % Input signal or Control vector
            uY = app.AccelerationinYEditField.Value*ones(1,length(t));      % Input signal or Control vector
            
            xXactual = xX;
            xYactual = xY;
            for i=1:numIters  
                yX(i) = C*xXactual;                 % Ideal Measurement output
                xXactual = A*xXactual + B*uX(i);
                
                yY(i) = C*xYactual;                 % Ideal Measurement output
                xYactual = A*xYactual + B*uY(i);
            end
            
            sigma_w = 0.1;
            mu_w = 0;
            amp_w = 1;
            noise_w = amp_w*sigma_w.*randn(1,length(t)) + mu_w;    % Process Noise
            Q = sigma_w^2;                                         % Process Noise Covariance Matrix
            
            sigma_v = 0.8;
            mu_v = 0;
            amp_v = 1;
            noise_v = amp_v*sigma_v.*randn(1,length(t)) + mu_v;    % Measurement Noise
            R = sigma_v^2;                                         % Measurement Noise Covariance Matrix
            
            ZX = yX + noise_v;         % Measurement output with measurement noise
            
            ZY = yY + noise_v;         % Measurement output with measurement noise
            
            % Predict-Update Process
            
            for i=1:numIters
                % Predict
                [x_hatX, PX] = predict(x_hat_previousX,P_previousX,A,B,uX(i),Q);
                [x_hatY, PY] = predict(x_hat_previousY,P_previousY,A,B,uY(i),Q);
                
                % Update
                [x_hat_updatedX, P_updatedX] = update(x_hatX,PX,C,ZX(i),R);
                y_hatX(i) = C*x_hat_updatedX;
                
                x_hat_previousX = x_hat_updatedX;
                P_previousX = P_updatedX;
                
                [x_hat_updatedY, P_updatedY] = update(x_hatY,PY,C,ZY(i),R);
                y_hatY(i) = C*x_hat_updatedY;
                
                x_hat_previousY = x_hat_updatedY;
                P_previousY = P_updatedY;
                
                % Plotting the Output Values
                
                if i==1
                    yXplot = yX(i);
                    yYplot = yY(i);
                    ZXplot = ZX(i);
                    ZYplot = ZY(i);
                else
                    yXplot = [yXplot yX(i)];
                    yYplot = [yYplot yY(i)];
                    ZXplot = [ZXplot ZX(i)];
                    ZYplot = [ZYplot ZY(i)];
                end  
                
                plot(app.UIAxes,yXplot,yYplot,'-b.',ZXplot,ZYplot,'--.',y_hatX,y_hatY,'-g.')
                legend(app.UIAxes,"Ideal Measurement Output","Measured Output","Estimated Output")
                pause(0.1);
            end
            
            % Predict Function
            
            function [newStateVector, newCovariance] = predict(previousStateVector,previousCovariance,stateTransitionMatrix,controlMatrix,controlVector,processNoiseCovariance)
                x_hat_previous = previousStateVector;
                P_previous = previousCovariance;
                A = stateTransitionMatrix;
                B = controlMatrix;
                u = controlVector;
                Q = processNoiseCovariance;
                
                x_hat = A*x_hat_previous + B*u;
                P = A*P_previous*A' + Q;
                
                newStateVector = x_hat;
                newCovariance = P;
            end
            
            % Update Function
            
            function [updatedStateVector, updatedCovariance] = update(StateVector,Covariance,measurementMatrix,measurementOutput,measurementNoiseCovariance)
                x_hat = StateVector;
                P = Covariance;
                C = measurementMatrix;
                Z = measurementOutput;
                R = measurementNoiseCovariance;
            
                K = P*C'/((C*P*C') + R);  % Kalman Gain
                x_hat_updated = x_hat + K * (Z - (C*x_hat));
                P_updated = (eye(size(C)) - (K*C)) * P;
                
                updatedStateVector = x_hat_updated;
                updatedCovariance = P_updated;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Kalman Filter with ideal measurement output, measured output and estimated output')
            xlabel(app.UIAxes, 'X-Position (in m)')
            ylabel(app.UIAxes, 'Y-Position (in m)')
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SamplingTimeTsLabel = uilabel(app.Panel);
            app.SamplingTimeTsLabel.Text = 'Sampling Time (Ts)';
            app.SamplingTimeTsEditField = uieditfield(app.Panel, 'numeric');
            app.SamplingTimeTsEditField.Value = 0.5;
            app.StopTimeEditFieldLabel = uilabel(app.Panel);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.Panel, 'numeric');
            app.StopTimeEditField.Value = 20;
            app.InitialPositioninXEditFieldLabel = uilabel(app.Panel);
            app.InitialPositioninXEditFieldLabel.Text = 'Initial Position in X';
            app.InitialPositioninXEditField = uieditfield(app.Panel, 'numeric');
            app.InitialPositioninYEditFieldLabel = uilabel(app.Panel);
            app.InitialPositioninYEditFieldLabel.Text = 'Initial Position in Y';
            app.InitialPositioninYEditField = uieditfield(app.Panel, 'numeric');
            app.InitialVelocityinXEditFieldLabel = uilabel(app.Panel);
            app.InitialVelocityinXEditFieldLabel.Text = 'Initial Velocity in X';
            app.InitialVelocityinXEditField = uieditfield(app.Panel, 'numeric');
            app.InitialVelocityinXEditField.Value = 10;
            app.InitialVelocityinYEditFieldLabel = uilabel(app.Panel);
            app.InitialVelocityinYEditFieldLabel.Text = 'Initial Velocity in Y';
            app.InitialVelocityinYEditField = uieditfield(app.Panel, 'numeric');
            app.InitialVelocityinYEditField.Value = 10;
            app.AccelerationinXEditField_2Label = uilabel(app.Panel);
            app.AccelerationinXEditField_2Label.Text = 'Acceleration in X';
            app.AccelerationinXEditField = uieditfield(app.Panel, 'numeric');
            app.AccelerationinYEditFieldLabel = uilabel(app.Panel);
            app.AccelerationinYEditFieldLabel.Text = 'Acceleration in Y';
            app.AccelerationinYEditField = uieditfield(app.Panel, 'numeric');
            app.AccelerationinYEditField.Value = -1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KalmanFilter2DExampleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end