classdef KalmanFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        UIAxes       matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes3      matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
Ts = 0.1;                 % Sampling time 
t_final = 10;             % Simulation end time
t = 0:Ts:t_final-Ts;      % Time axis
numIters = length(t);     % Number of iterations

x = [0;0];               % Initial State Vector
x_hat = x;               % Initial State Vector Estimate
P = [1 0;0 1];           % Initial Covariance Matrix
x_hat_previous = x;      % Previous State Vector
P_previous = P;          % Previous Covariance Matrix

A = [0.01   -0.4940 ; 0.1129 -0.3832];    % State Transition Matrix or Prediction Matrix
B = [0.5919 ; 0.5191];                    % Input Control Matrix
C = [1 0];                                % Measurement Matrix

u = sin(2*pi*0.025*t);      % Input signal or Control vector
Plant = ss(A,B,C,0,Ts);     % Plant
y = lsim(Plant,u,t)';       % Ideal measurement output
y_hat = zeros(1,length(y)); % Initial measurement output estimate

sigma_w = 0.2;
mu_w = 0;
amp_w = 0.1;
noise_w = amp_w*sigma_w.*randn(1,length(t)) + mu_w;    % Process Noise
Q = sigma_w^2;                                         % Process Noise Covariance Matrix

sigma_v = 0.5;
mu_v = 0;
amp_v = 0.1;
noise_v = amp_v*sigma_v.*randn(1,length(t)) + mu_v;    % Measurement Noise
R = sigma_v^2;                                         % Measurement Noise Covariance Matrix

Z = y + noise_v;         % Measurement output with measurement noise

% Errors
measError = zeros(1,length(y));
estError = zeros(1,length(y));

% Distributions
pdf_time = -1:0.01:1-0.01;
pdf_current = zeros(length(pdf_time),length(t));
pdf_estimated = zeros(length(pdf_time),length(t));
pdf_measurement = zeros(length(pdf_time),length(t));

%% Predict-Update Process

for i=1:numIters
    % Predict
    x_hat = A*x_hat_previous + B*u(i);
    P = A*P_previous*A' + Q;
    
    % Update
    K = P*C'/((C*P*C') + R);  % Kalman Gain
    x_hat_updated = x_hat + K * (Z(i) - (C*x_hat));
    P_updated = (eye(size(C)) - (K*C)) * P;
    y_hat(i) = C*x_hat_updated;
    
    x_hat_previous = x_hat_updated;
    P_previous = P_updated;

    % Errors
    measError(i) = y(i) - Z(i);
    estError(i) = y(i) - y_hat(i);
    
    % Distributions
    pdf_current(:,i) = normpdf(pdf_time,x_hat(1),P(1));
    pdf_measurement(:,i) = normpdf(pdf_time,Z(i),R);
    pdf_estimated(:,i) = normpdf(pdf_time,x_hat_updated(1),P_updated(1));
    
    % Plotting the Output Values, Error Values and Distributions
    if i==1
        timePlot = t(i);
        yPlot = y(i);
        ZPlot = Z(i);
        y_hatPlot = y_hat(i);
        measErrorPlot = measError(i);
        estErrorPlot = estError(i);
    else
        timePlot = [timePlot t(i)];
        yPlot = [yPlot y(i)];
        ZPlot = [ZPlot Z(i)];
        y_hatPlot = [y_hatPlot y_hat(i)];
        measErrorPlot = [measErrorPlot measError(i)];
        estErrorPlot = [estErrorPlot estError(i)];
    end   
    
    plot(app.UIAxes,timePlot,yPlot,'-b.',timePlot,ZPlot,'--.',timePlot,y_hatPlot,'-g.');  
    legend (app.UIAxes, 'Ideal Measurement Output','Measured Output','Estimated Output');
    
    plot(app.UIAxes2,timePlot,measErrorPlot,'-r.',timePlot,estErrorPlot,'-g.');
    legend(app.UIAxes2, 'Measured Error','Estimated Error');
    
    plot(app.UIAxes3,pdf_time,pdf_current(:,i),'-g',pdf_time,pdf_measurement(:,i),'-r',pdf_time,pdf_estimated(:,i),'-b');
    legend(app.UIAxes3, 'Current State Estimate','Measured Output','Next Estimated State');
    
    pause(0.1);
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, {'Kalman Filter with ideal measurement output, '; 'measured output and estimated output'})
            xlabel(app.UIAxes, 'Time (in sec)')
            ylabel(app.UIAxes, 'Output values')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-0.1 1];
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, {'Errors in Measured output '; 'and Estimated output'})
            xlabel(app.UIAxes2, 'Time (in sec)')
            ylabel(app.UIAxes2, 'Error values')
            app.UIAxes2.XLim = [0 10];
            app.UIAxes2.YLim = [-0.15 0.3];
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, {'Gaussian Distributions of'; 'State Estimates and Measurement'})
            xlabel(app.UIAxes3, 'Output Values')
            ylabel(app.UIAxes3, 'Density')
            app.UIAxes3.XLim = [-1 1];
            app.UIAxes3.YLim = [0 20];
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KalmanFilterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end